classdef MTO_Platform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Panel_1                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        ProblemsAddButton       matlab.ui.control.Button
        AlgorithmsAddButton     matlab.ui.control.Button
        RepsEditField           matlab.ui.control.NumericEditField
        RunTimesEditFieldLabel  matlab.ui.control.Label
        EndNumEditField         matlab.ui.control.NumericEditField
        EndNumEditFieldLabel    matlab.ui.control.Label
        PopSizeEditField        matlab.ui.control.NumericEditField
        PopSizeEditFieldLabel   matlab.ui.control.Label
        AlgorithmsListBox       matlab.ui.control.ListBox
        AlgorithmsListBoxLabel  matlab.ui.control.Label
        ProblemsListBox         matlab.ui.control.ListBox
        ProblemsListBoxLabel    matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        IterButton              matlab.ui.control.RadioButton
        EvaluateButton          matlab.ui.control.RadioButton
        EndConditionLabel       matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        StartButton             matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        AlgorithmsTree          matlab.ui.container.Tree
        ProblemsTree            matlab.ui.container.Tree
        SelectedAlgoLabel       matlab.ui.control.Label
        SelectedProbLabel       matlab.ui.control.Label
        AlgorithmsDelButton     matlab.ui.control.Button
        ProblemsDelButton       matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        ConvergenceTrendTab     matlab.ui.container.Tab
        GridLayout7             matlab.ui.container.GridLayout
        Task1UIAxes             matlab.ui.control.UIAxes
        Task2UIAxes             matlab.ui.control.UIAxes
        ResultTable             matlab.ui.container.Tab
        GridLayout8_2           matlab.ui.container.GridLayout
        ResultUITable           matlab.ui.control.Table
        TimeTable               matlab.ui.container.Tab
        GridLayout8             matlab.ui.container.GridLayout
        TimeUITable             matlab.ui.control.Table
        Panel_4                 matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        LogsTextAreaLabel       matlab.ui.control.Label
        LogsTextArea            matlab.ui.control.TextArea
        LogsClearButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        reps
        pop_size
        end_num
        result
    end
    
    methods (Access = public)
        
        function printlog(app, str)
            if strcmp(app.LogsTextArea.Value, '')
                app.LogsTextArea.Value = str;
            else
                app.LogsTextArea.Value = [app.LogsTextArea.Value; str];
            end
            scroll(app.LogsTextArea, 'bottom')
            drawnow;
        end
        
        function loadAlgoProb(app)
            % load the algorithms and problems to list
            app.AlgorithmsListBox.Items(:) = [];
            app.ProblemsListBox.Items(:) = [];
            algo_load = {};
            prob_load = {};
            algo_dir = dir('Algorithms/');
            prob_dir = dir('Problems/*.m');
            
            % Algorithms name is folder's name
            for i=1:length(algo_dir)
                if algo_dir(i).isdir
                    algo_load  = [algo_load, algo_dir(i).name];
                end
            end
            algo_load(strcmp(algo_load, '.')) = [];
            algo_load(strcmp(algo_load, '..')) = [];
            algo_load(strcmp(algo_load, 'Base')) = [];
            app.AlgorithmsListBox.Items = algo_load;
            app.AlgorithmsListBox.Value = algo_load;
            
            % Problems name is .m files name
            for i=1:length(prob_dir)
                prob_load  = [prob_load, prob_dir(i).name(1:end-2)];
            end
            app.ProblemsListBox.Items = prob_load;
            app.ProblemsListBox.Value = prob_load;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path
            addpath(genpath('./Algorithms/'));
            addpath(genpath('./Problems/'));
            addpath(genpath('./Utils/'));
            
            app.loadAlgoProb();
            
            % read default value
            app.reps = app.RepsEditField.Value;
            app.pop_size = app.PopSizeEditField.Value;
            app.end_num = app.EndNumEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            algo_num = length(app.AlgorithmsTree.Children);
            prob_num = length(app.ProblemsTree.Children);
            
            if algo_num == 0
                app.printlog('!!! Please select the Algorithm first !!!');
                return;
            end
            if prob_num ==0
                app.printlog('!!! Please select the Problem first !!!');
                return;
            end
            
            % initialize the result properties
            for algo = 1:algo_num
                for prob = 1:prob_num
                    app.result(algo, prob).clock_time = 0;
                    app.result(algo, prob).convergence = [];
                end
            end
            
            for algo = 1:algo_num
                algo_list{algo} = app.AlgorithmsTree.Children(algo).Text;
            end
            
            for prob = 1:prob_num
                prob_list{prob} = app.ProblemsTree.Children(prob).Text;
            end
            
            % main test loop
            app.printlog([newline, '========= Test Start ========='])
            tStart = tic;
            for prob = 1:prob_num
                app.printlog(['<-------- Problem: ', app.ProblemsTree.Children(prob).Text, ' -------->']);
                
                for rep = 1:app.reps
                    app.printlog(['------- Rep: ', num2str(rep), ' -------']);
                    
                    for algo = 1:algo_num
                        app.printlog([app.AlgorithmsTree.Children(algo).Text, ' is running']);
                        if app.IterButton.Value
                            iter_num = app.end_num;
                            eva_num = inf;
                        elseif app.EvaluateButton.Value
                            iter_num = inf;
                            eva_num = app.end_num;
                        end
                        pre_run_list = [app.pop_size, iter_num, eva_num];
                        data = singleRun(app.AlgorithmsTree.Children(algo).NodeData, app.ProblemsTree.Children(prob).NodeData, pre_run_list);
                        app.result(algo, prob).clock_time = app.result(algo, prob).clock_time + data.clock_time;
                        app.result(algo, prob).convergence = [app.result(algo, prob).convergence; data.convergence];
                    end
                    
                end
                
            end
            
            tEnd = toc(tStart);
            app.printlog(['<------- All Use Time: ', char(duration([0, 0, tEnd])), ' ------->']);
            app.printlog(['======== Test Finished ========', newline]);
            
            % save the data
            data_save.reps = app.reps;
            data_save.pop_size = app.pop_size;
            data_save.iter_num = iter_num;
            data_save.eva_num = eva_num;
            data_save.algo_list = algo_list';
            data_save.prob_list = prob_list;
            data_save.result = app.result;
            save('data_save', 'data_save');
        end

        % Button pushed function: AlgorithmsAddButton
        function AlgorithmsAddButtonPushed(app, event)
            algo_selected = app.AlgorithmsListBox.Value;
            for i= 1:length(algo_selected)
                eval(['algo_obj = ', algo_selected{i}, '("',algo_selected{i}, '");']);
                algo_node = uitreenode(app.AlgorithmsTree);
                algo_node.Text = algo_obj.getName();
                algo_node.NodeData = algo_obj;
                
                % TODO child parameter node
                parameter = algo_obj.getParameter();
                for p = 1:length(parameter)
                    para_name_node = uitreenode(algo_node);
                    para_name_node.Text = [parameter(p).name, ':'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_value_node = uitreenode(algo_node);
                    para_value_node.Text = num2str(parameter(p).value);
                end
            end
        end

        % Button pushed function: ProblemsAddButton
        function ProblemsAddButtonPushed(app, event)
            prob_selected = app.ProblemsListBox.Value;
            for i= 1:length(prob_selected)
                eval(['prob_obj = ', prob_selected{i}, '("',prob_selected{i}, '");']);
                node = uitreenode(app.ProblemsTree);
                node.Text = prob_obj.getName();
                node.NodeData = prob_obj;
                % TODO child parameter node
            end
        end

        % Button pushed function: AlgorithmsDelButton
        function AlgorithmsDelButtonPushed(app, event)
            algo_selected = app.AlgorithmsTree.SelectedNodes;
            for i = 1:length(algo_selected)
                if isa(algo_selected(i).Parent, 'matlab.ui.container.Tree')
                    algo_selected(i).delete;
                end
            end
        end

        % Button pushed function: ProblemsDelButton
        function ProblemsDelButtonPushed(app, event)
            prob_selected = app.ProblemsTree.SelectedNodes;
            for i = 1:length(prob_selected)
                if isa(prob_selected(i).Parent, 'matlab.ui.container.Tree')
                    prob_selected(i).delete;
                end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
        end

        % Button pushed function: LogsClearButton
        function LogsClearButtonPushed(app, event)
            app.LogsTextArea.Value = '';
        end

        % Value changed function: RepsEditField
        function RepsEditFieldValueChanged(app, event)
            app.reps = app.RepsEditField.Value;
        end

        % Value changed function: PopSizeEditField
        function PopSizeEditFieldValueChanged(app, event)
            app.pop_size = app.PopSizeEditField.Value;
        end

        % Value changed function: EndNumEditField
        function EndNumEditFieldValueChanged(app, event)
            app.end_num = app.EndNumEditField.Value;
        end

        % Node text changed function: AlgorithmsTree
        function AlgorithmsTreeNodeTextChanged(app, event)
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % Algorithm node
                node.NodeData.name = node.Text;
            else
                % Parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Node text changed function: ProblemsTree
        function ProblemsTreeNodeTextChanged(app, event)
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % Problem node
                node.NodeData.name = node.Text;
            else
                % Parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_1 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_1);
            app.ProblemsAddButton = uibutton(app.GridLayout3, 'push');
            app.ProblemsAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsAddButtonPushed, true);
            app.ProblemsAddButton.Text = 'Add';
            app.AlgorithmsAddButton = uibutton(app.GridLayout3, 'push');
            app.AlgorithmsAddButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsAddButtonPushed, true);
            app.AlgorithmsAddButton.Text = 'Add';
            app.RepsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RepsEditField.ValueChangedFcn = createCallbackFcn(app, @RepsEditFieldValueChanged, true);
            app.RepsEditField.Value = 20;
            app.RunTimesEditFieldLabel = uilabel(app.GridLayout3);
            app.RunTimesEditFieldLabel.Text = 'Run Times';
            app.EndNumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EndNumEditField.ValueChangedFcn = createCallbackFcn(app, @EndNumEditFieldValueChanged, true);
            app.EndNumEditField.Value = 1000;
            app.EndNumEditFieldLabel = uilabel(app.GridLayout3);
            app.EndNumEditFieldLabel.Text = 'End Num';
            app.PopSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PopSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopSizeEditFieldValueChanged, true);
            app.PopSizeEditField.Value = 100;
            app.PopSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.PopSizeEditFieldLabel.Text = 'Pop Size';
            app.AlgorithmsListBox = uilistbox(app.GridLayout3);
            app.AlgorithmsListBox.Items = {'MFEA', 'MFDE', 'MFPSO', 'MFGBO', 'MFEA_AKT', 'MFEA2'};
            app.AlgorithmsListBox.Multiselect = 'on';
            app.AlgorithmsListBox.Value = {'MFEA'};
            app.AlgorithmsListBoxLabel = uilabel(app.GridLayout3);
            app.AlgorithmsListBoxLabel.Text = 'Algorithms';
            app.ProblemsListBox = uilistbox(app.GridLayout3);
            app.ProblemsListBox.Items = {'CI_H', 'CI_M', 'CI_L'};
            app.ProblemsListBox.Multiselect = 'on';
            app.ProblemsListBox.Value = {};
            app.ProblemsListBoxLabel = uilabel(app.GridLayout3);
            app.ProblemsListBoxLabel.Text = 'Problems';
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.Tooltip = {''};
            app.IterButton = uiradiobutton(app.ButtonGroup);
            app.IterButton.Text = 'Iter';
            app.IterButton.Value = true;
            app.EvaluateButton = uiradiobutton(app.ButtonGroup);
            app.EvaluateButton.Text = 'Evaluate';
            app.EndConditionLabel = uilabel(app.GridLayout3);
            app.EndConditionLabel.Text = 'End Condition';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.GridLayout2, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AlgorithmsTree = uitree(app.GridLayout2);
            app.AlgorithmsTree.Multiselect = 'on';
            app.AlgorithmsTree.NodeTextChangedFcn = createCallbackFcn(app, @AlgorithmsTreeNodeTextChanged, true);
            app.AlgorithmsTree.Editable = 'on';
            app.ProblemsTree = uitree(app.GridLayout2);
            app.ProblemsTree.Multiselect = 'on';
            app.ProblemsTree.NodeTextChangedFcn = createCallbackFcn(app, @ProblemsTreeNodeTextChanged, true);
            app.ProblemsTree.Editable = 'on';
            app.SelectedAlgoLabel = uilabel(app.GridLayout2);
            app.SelectedAlgoLabel.Text = 'Selected Algo';
            app.SelectedProbLabel = uilabel(app.GridLayout2);
            app.SelectedProbLabel.Text = 'Selected Prob';
            app.AlgorithmsDelButton = uibutton(app.GridLayout2, 'push');
            app.AlgorithmsDelButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsDelButtonPushed, true);
            app.AlgorithmsDelButton.Text = 'Del';
            app.ProblemsDelButton = uibutton(app.GridLayout2, 'push');
            app.ProblemsDelButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsDelButtonPushed, true);
            app.ProblemsDelButton.Text = 'Del';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.ConvergenceTrendTab = uitab(app.TabGroup);
            app.ConvergenceTrendTab.Title = 'Convergence Trend';
            app.GridLayout7 = uigridlayout(app.ConvergenceTrendTab);
            app.Task1UIAxes = uiaxes(app.GridLayout7);
            title(app.Task1UIAxes, 'Task 1')
            xlabel(app.Task1UIAxes, 'Iteration')
            ylabel(app.Task1UIAxes, 'log(fitness)')
            zlabel(app.Task1UIAxes, 'Z')
            app.Task2UIAxes = uiaxes(app.GridLayout7);
            title(app.Task2UIAxes, 'Task 2')
            xlabel(app.Task2UIAxes, 'Iteration')
            ylabel(app.Task2UIAxes, 'log(fitness)')
            zlabel(app.Task2UIAxes, 'Z')
            app.ResultTable = uitab(app.TabGroup);
            app.ResultTable.Title = 'Result Table';
            app.GridLayout8_2 = uigridlayout(app.ResultTable);
            app.ResultUITable = uitable(app.GridLayout8_2);
            app.ResultUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultUITable.RowName = {};
            app.TimeTable = uitab(app.TabGroup);
            app.TimeTable.Title = 'Time Table';
            app.GridLayout8 = uigridlayout(app.TimeTable);
            app.TimeUITable = uitable(app.GridLayout8);
            app.TimeUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TimeUITable.RowName = {};
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.LogsTextAreaLabel = uilabel(app.GridLayout6);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.GridLayout6);
            app.LogsTextArea.Editable = 'off';
            app.LogsClearButton = uibutton(app.GridLayout6, 'push');
            app.LogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @LogsClearButtonPushed, true);
            app.LogsClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTO_Platform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end