classdef MTO_Platform2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel_1                  matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        ProblemsDelButton        matlab.ui.control.Button
        AlgorithmsDelButton      matlab.ui.control.Button
        conditionSwitch          matlab.ui.control.Switch
        ProblemsAddButton        matlab.ui.control.Button
        AlgorithmsAddButton      matlab.ui.control.Button
        RepsEditField            matlab.ui.control.NumericEditField
        RunTimesEditFieldLabel   matlab.ui.control.Label
        EndNumEditField          matlab.ui.control.NumericEditField
        EndNumEditFieldLabel     matlab.ui.control.Label
        PopSizeEditField         matlab.ui.control.NumericEditField
        PopSizeEditFieldLabel    matlab.ui.control.Label
        AlgorithmsListBox        matlab.ui.control.ListBox
        AlgorithmsListBoxLabel   matlab.ui.control.Label
        ProblemsListBox          matlab.ui.control.ListBox
        ProblemsListBoxLabel     matlab.ui.control.Label
        EndTypeLabel             matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        StartButton              matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        AlgorithmsTree           matlab.ui.container.Tree
        ProblemsTree             matlab.ui.container.Tree
        SelectedAlgorithmsLabel  matlab.ui.control.Label
        SelectedProblemsLabel    matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        ConvergenceTrendTab      matlab.ui.container.Tab
        GridLayout7              matlab.ui.container.GridLayout
        Task2UIAxes              matlab.ui.control.UIAxes
        Task1UIAxes              matlab.ui.control.UIAxes
        ResultTable              matlab.ui.container.Tab
        GridLayout8_2            matlab.ui.container.GridLayout
        ResultUITable            matlab.ui.control.Table
        TimeTable                matlab.ui.container.Tab
        GridLayout8              matlab.ui.container.GridLayout
        TimeUITable              matlab.ui.control.Table
        Panel_4                  matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        LogsTextAreaLabel        matlab.ui.control.Label
        LogsTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        algorithms_list = {}
        problems_list = {}
        pop_size
        reps
        iter_num
        eva_num
        is_cancel
        result
    end
    
    methods (Access = public)
        
        function update_list(app)
            % update algorithms
            app.AlgorithmsTree.Children.delete;
            for i = 1:length(app.algorithms_list)
                node(i) = uitreenode(app.AlgorithmsTree);
                node(i).Text = app.algorithms_list{i};
            end
            
            % update problems
            app.ProblemsTree.Children.delete;
            for i = 1:length(app.problems_list)
                node(i) = uitreenode(app.ProblemsTree);
                node(i).Text = app.problems_list{i};
            end
        end
        
        function printlog(app, str)
            app.LogsTextArea.Value = [app.LogsTextArea.Value; str];
        end
        
        function is_cancel = isCancel(app)
            is_cancel = app.is_cancel;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.is_cancel = 0;
            app.pop_size = app.PopSizeEditField.Value;
            app.reps = app.RepsEditField.Value;
            switch(app.conditionSwitch.Value)
                case 'Iter'
                    app.iter_num = app.EndNumEditField.Value;
                    app.eva_num = inf;
                case 'Eval'
                    app.iter_num = inf;
                    app.eva_num = app.EndNumEditField.Value;
            end
            
            % create Management and run
            addpath(genpath('./Utils'))
            manage = Management(app.reps, app.pop_size, app.iter_num, app.eva_num);
%             manage.start(app.algorithms_list, app.problems_list, app);

            % initialize the result properties
            for algo = 1:length(app.algorithms_list)

                for prob = 1:length(app.problems_list)
                    app.result(algo, prob).clock_time = 0;
                    app.result(algo, prob).convergence = [];
                end

            end
            
            % main test loop
            for prob = 1:length(app.problems_list)
                app.printlog(['<- Problem: ', app.problems_list{prob}, ' ->']);

                for rep = 1:app.reps
                    app.printlog(['Rep: ', num2str(rep)]);

                    for algo = 1:length(app.algorithms_list)
                        app.printlog([app.algorithms_list{algo}]);
                        data = manage.singleRun(app.algorithms_list{algo}, app.problems_list{prob});
                        app.result(algo, prob).clock_time = app.result(algo, prob).clock_time + data.clock_time;
                        app.result(algo, prob).convergence = [app.result(algo, prob).convergence; data.convergence];

                        % app.update()

                        if app.isCancel()
                            app.printlog('!!! Canceled !!!');
                            return;
                        end

                    end

                end

            end
            
            % save the data
            savedata.reps = app.reps;
            savedata.pop_size = app.pop_size;
            savedata.iter_num = app.iter_num;
            savedata.algo_list = app.algorithms_list';
            savedata.prob_list = app.problems_list;
            savedata.result = app.result;
            save('savedata', 'savedata');
        end

        % Button pushed function: AlgorithmsAddButton
        function AlgorithmsAddButtonPushed(app, event)
            algo_selected = app.AlgorithmsListBox.Value;
            app.algorithms_list = unique([app.algorithms_list, algo_selected]);
            app.update_list();
        end

        % Button pushed function: ProblemsAddButton
        function ProblemsAddButtonPushed(app, event)
            prob_selected = app.ProblemsListBox.Value;
            app.problems_list = unique([app.problems_list, prob_selected]);
            app.update_list();
        end

        % Button pushed function: AlgorithmsDelButton
        function AlgorithmsDelButtonPushed(app, event)
            algo_selected = app.AlgorithmsListBox.Value;
            for i = 1:length(algo_selected)
                app.algorithms_list(find(strcmp(app.algorithms_list, algo_selected{i}))) = [];
            end
            app.update_list();
        end

        % Button pushed function: ProblemsDelButton
        function ProblemsDelButtonPushed(app, event)
            prob_selected = app.ProblemsListBox.Value;
            for i = 1:length(prob_selected)
                app.problems_list(find(strcmp(app.problems_list, prob_selected{i}))) = [];
            end
            app.update_list();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.is_cancel = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_1 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_1);
            app.ProblemsDelButton = uibutton(app.GridLayout3, 'push');
            app.ProblemsDelButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsDelButtonPushed, true);
            app.ProblemsDelButton.Text = '-';
            app.AlgorithmsDelButton = uibutton(app.GridLayout3, 'push');
            app.AlgorithmsDelButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsDelButtonPushed, true);
            app.AlgorithmsDelButton.Text = '-';
            app.conditionSwitch = uiswitch(app.GridLayout3, 'slider');
            app.conditionSwitch.Items = {'Iter', 'Eva'};
            app.conditionSwitch.Value = 'Iter';
            app.ProblemsAddButton = uibutton(app.GridLayout3, 'push');
            app.ProblemsAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemsAddButtonPushed, true);
            app.ProblemsAddButton.Text = '+';
            app.AlgorithmsAddButton = uibutton(app.GridLayout3, 'push');
            app.AlgorithmsAddButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmsAddButtonPushed, true);
            app.AlgorithmsAddButton.Text = '+';
            app.RepsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RepsEditField.Value = 20;
            app.RunTimesEditFieldLabel = uilabel(app.GridLayout3);
            app.RunTimesEditFieldLabel.Text = 'Reps';
            app.EndNumEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EndNumEditField.Value = 1000;
            app.EndNumEditFieldLabel = uilabel(app.GridLayout3);
            app.EndNumEditFieldLabel.Text = 'End Num';
            app.PopSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PopSizeEditField.Value = 100;
            app.PopSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.PopSizeEditFieldLabel.Text = 'Pop Size';
            app.AlgorithmsListBox = uilistbox(app.GridLayout3);
            app.AlgorithmsListBox.Items = {'MFEA', 'MFDE', 'MFPSO', 'MFGBO', 'MFEA_AKT', 'MFEA2'};
            app.AlgorithmsListBox.Multiselect = 'on';
            app.AlgorithmsListBox.Value = {'MFEA'};
            app.AlgorithmsListBoxLabel = uilabel(app.GridLayout3);
            app.AlgorithmsListBoxLabel.Text = 'Algorithms';
            app.ProblemsListBox = uilistbox(app.GridLayout3);
            app.ProblemsListBox.Items = {'CI_H', 'CI_M', 'CI_L'};
            app.ProblemsListBox.Multiselect = 'on';
            app.ProblemsListBox.Value = {'CI_H', 'CI_M', 'CI_L'};
            app.ProblemsListBoxLabel = uilabel(app.GridLayout3);
            app.ProblemsListBoxLabel.Text = 'Problems';
            app.EndTypeLabel = uilabel(app.GridLayout3);
            app.EndTypeLabel.Text = 'End Type';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.GridLayout2, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AlgorithmsTree = uitree(app.GridLayout2);
            app.ProblemsTree = uitree(app.GridLayout2);
            app.SelectedAlgorithmsLabel = uilabel(app.GridLayout2);
            app.SelectedAlgorithmsLabel.Text = 'Selected Algorithms';
            app.SelectedProblemsLabel = uilabel(app.GridLayout2);
            app.SelectedProblemsLabel.Text = 'Selected Problems';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.ConvergenceTrendTab = uitab(app.TabGroup);
            app.ConvergenceTrendTab.Title = 'Convergence Trend';
            app.GridLayout7 = uigridlayout(app.ConvergenceTrendTab);
            app.Task2UIAxes = uiaxes(app.GridLayout7);
            title(app.Task2UIAxes, 'Task 2')
            xlabel(app.Task2UIAxes, 'Iteration')
            ylabel(app.Task2UIAxes, 'log(fitness)')
            zlabel(app.Task2UIAxes, 'Z')
            app.Task1UIAxes = uiaxes(app.GridLayout7);
            title(app.Task1UIAxes, 'Task 1')
            xlabel(app.Task1UIAxes, 'Iteration')
            ylabel(app.Task1UIAxes, 'log(fitness)')
            zlabel(app.Task1UIAxes, 'Z')
            app.ResultTable = uitab(app.TabGroup);
            app.ResultTable.Title = 'Result Table';
            app.GridLayout8_2 = uigridlayout(app.ResultTable);
            app.ResultUITable = uitable(app.GridLayout8_2);
            app.ResultUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultUITable.RowName = {};
            app.TimeTable = uitab(app.TabGroup);
            app.TimeTable.Title = 'Time Table';
            app.GridLayout8 = uigridlayout(app.TimeTable);
            app.TimeUITable = uitable(app.GridLayout8);
            app.TimeUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TimeUITable.RowName = {};
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.LogsTextAreaLabel = uilabel(app.GridLayout6);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.GridLayout6);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTO_Platform2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end